classdef COVID19APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        CountryListBoxLabel        matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        StateorRegionListBoxLabel  matlab.ui.control.Label
        StateorRegionListBox       matlab.ui.control.ListBox
        DataToPlotButtonGroup      matlab.ui.container.ButtonGroup
        CasesButton                matlab.ui.control.RadioButton
        DeathButton                matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        CumulativeButton           matlab.ui.control.RadioButton
        DailyButton                matlab.ui.control.RadioButton
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        AveragedofdaysSlider       matlab.ui.control.Slider
    end

    properties (Access = private)
        AveragingWindow % width of averaging window (in days)
        Dates  % measurement time (in the format 'dd/MM/yyyy')
        SelectedCountry  % country selected in the list box
        SelectedState    % State selected from the states box
        PlotTile         % title of plot
        CountryObjects   % vector to hold objects of the countries
        DataToPlot       % swtiches between case and deaths
        PlotTileName     % name of region being plotted
        Slider = "";     % string for determing slider vaue to add to title
    end
    
    methods (Access = private)
        % preprocessing function. Process the data to extract numeric
        % values, states, dates, and country
        function [Cases, Deaths, dates, States, Countries] = preprocessData(~, covid_data)
            [Cases, Deaths] = deal([]); % initializing the variables
            [m, n] = size(covid_data);
            [Countries, States, dates] = deal(string(zeros(m, 1)));
            
            for idx = 1:m
                Countries(idx) = string(covid_data{idx, 1});
                if isempty(covid_data{idx, 2})          % check if entire country data (thus, if state is empty)
                    name = string(covid_data{idx, 1}) + "/" + "All";    % add to "/All" as state
                else
                    name = string(covid_data{idx, 1}) + "/" + string(covid_data{idx, 2});  % else add the "/{state name}"
                end
                States(idx) = name;   % add the concatenated country/state
                if idx==1              % first row of the covid_data is the time. Hence we extract that
                    for iter=3:n
                        dates(iter-2, idx) = string(covid_data{idx, iter});
                    end
                    continue;
                end
                for iter=3:n                % looping through for cases & death data for each day
                    tmp = covid_data{idx, iter};
                    Cases(iter-2, idx-1) = tmp(1);
                    Deaths(iter-2, idx-1) = tmp(2);
                end
            end
            Cases = Cases'; Deaths = Deaths'; dates = datetime(dates, "Format","dd/MM/yy");
            States = States(2:end);
            Countries  = unique(Countries(2:end)); % removing the first element (thus, country)
            
            % country positions and computing the global data
            countryPos = find(endsWith(States, "/All"));  % extracting the country positions in the data
            global_cases = sum(Cases(countryPos, :));     % computing the global cases & deaths
            global_death = sum(Deaths(countryPos, :));
            
            % combining the global data to the rest
            Cases = [global_cases; Cases];  % adding the global cases data to cases
            Deaths = [global_death; Deaths]; % adding the global cases data to cases
            Countries = ["Global"; Countries];  % appending "Global" & "All" to countries and states
            States = ["Global/All"; States];
        end
        
        % function to compute average
        function result = computeAverages(app, data)
            if app.AveragingWindow>1  % window is >+1, compute average
                result = movmean(data, [app.AveragingWindow-1, 0]);
            else        % else, no averaging
                result = data;
            end
        end
        
        function plotData(app)
            cla(app.UIAxes, 'reset');  % this resets the axes for new plot each time
            switch(app.OptionButtonGroup.SelectedObject.Text) % the outer switch, switches over options radio button group
                
                case "Cumulative"   % for cumlative data
                    
                    switch(app.DataToPlotButtonGroup.SelectedObject.Text) % the inner switch, switches over the data to plot group
                        case "Cases"
                            app.PlotTile = "Cumulative Number of Cases " + app.PlotTileName + app.Slider; % the title
                            app.DataToPlot = app.computeAverages(app.SelectedState.CumulativeCases);  % computing the averaged data
                            bar(app.UIAxes, app.Dates, app.DataToPlot);         % bar plot of cumulative cases
                        case "Death"
                            app.PlotTile = "Cumulative Number of Deaths " + app.PlotTileName + app.Slider;
                            app.DataToPlot = app.computeAverages(app.SelectedState.CumulativeDeath);
                            plot(app.UIAxes, app.Dates, app.DataToPlot, "r",'LineWidth',1);  % plotting the cumulative death data
                        case "Both"
                            app.PlotTile = "Cumulative Number of Cases and Deaths " + app.PlotTileName + app.Slider;
                            yyaxis(app.UIAxes, "left");  % setting y axis for the first plot to the left
                            app.UIAxes.YLabel.String = "Counts";
                            app.DataToPlot = app.computeAverages(app.SelectedState.CumulativeCases);
                            bar(app.UIAxes, app.Dates, app.DataToPlot);
                            app.UIAxes.YTickLabel = (arrayfun(@(x) {string(x)}, app.UIAxes.YTick));
                            hold(app.UIAxes, 'on');
                            yyaxis(app.UIAxes, "right");   % setting y axis for the second plot to the right
                            app.DataToPlot = app.computeAverages(app.SelectedState.CumulativeDeath);
                            plot(app.UIAxes, app.Dates, app.DataToPlot, "r",'LineWidth',1);
                    end
                    
                case "Daily"     % for daily data
                    
                    switch(app.DataToPlotButtonGroup.SelectedObject.Text)
                        case "Cases"
                            app.PlotTile = "Daily Number of Cases " + app.PlotTileName + app.Slider;
                            app.DataToPlot = app.computeAverages(app.SelectedState.DailyCases);
                            bar(app.UIAxes, app.Dates, app.DataToPlot);
                        case "Death"
                            app.PlotTile = "Daily Number of Deaths " + app.PlotTileName  + app.Slider;
                            app.DataToPlot = app.computeAverages(app.SelectedState.DailyDeath);
                            plot(app.UIAxes, app.Dates, app.DataToPlot, "r",'LineWidth',1);
                        case "Both"
                            app.PlotTile = "Daily Number of Cases and Deaths " + app.PlotTileName + app.Slider;
                            yyaxis(app.UIAxes, "left"); app.UIAxes.YLabel.String = "Counts";
                            app.DataToPlot = app.computeAverages(app.SelectedState.DailyCases);
                            bar(app.UIAxes, app.Dates, app.DataToPlot);
                            app.UIAxes.YTickLabel = (arrayfun(@(x) {string(x)}, app.UIAxes.YTick));
                            hold(app.UIAxes, 'on');
                            yyaxis(app.UIAxes, "right");
                            app.DataToPlot = app.computeAverages(app.SelectedState.DailyDeath);
                            plot(app.UIAxes, app.Dates, app.DataToPlot, "r",'LineWidth',1);
                            hold(app.UIAxes, "off");
                    end
                    
            end
            % setting the axes preferences again since it was reset
            app.UIAxes.Title.String = app.PlotTile;
            len = ceil(days(app.Dates(end) - app.Dates(1))/30);
            x_axis = linspace(app.Dates(1), app.Dates(end), len);
            x_axis = datetime(x_axis, "Format","MM/yy");
            app.UIAxes.XTick = x_axis;  % the x-axis ticks
            app.UIAxes.XTickLabel = (arrayfun(@(x) {string(x)}, x_axis)); % the x-axis ticks
            app.UIAxes.BackgroundColor = [0.93,0.92,0.79];
            app.UIAxes.FontName = "Comic Sans MS";
            app.UIAxes.FontSize = 13;
            app.UIAxes.FontWeight = "bold";
            app.UIAxes.YTickLabel = (arrayfun(@(x) {string(x)}, app.UIAxes.YTick));  % setting the ylabel style
            app.UIAxes.XLabel.String = "Dates"; app.UIAxes.YLabel.String = "Counts";
            grid(app.UIAxes, 'on'); axis(app.UIAxes, 'tight');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat  covid_data;
            [Cases, Deaths, dates, States, Countries] = preprocessData(app, covid_data);
            app.Dates = dates; % setting the date property
            
            % initializing the averaging window length
            app.AveragingWindow = app.AveragedofdaysSlider.Value;
            % creating instances of the countries
            app.CountryObjects = arrayfun(@(x) LandArea(x, Cases, Deaths, States), Countries);
            
            % initializing the elements of the country list box
            app.CountryListBox.Items = arrayfun(@(x) {x.Name}, app.CountryObjects);
            % setting the default country
            app.CountryListBox.Value = app.CountryListBox.Items{1};  % default country
            app.CountryListBoxValueChanged() % to initialize the State list box
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            pos = strcmp(app.CountryListBox.Items, app.CountryListBox.Value);
            app.SelectedCountry = app.CountryObjects(pos); % selected country
            % setting the States list box for the based on the selected country
            app.StateorRegionListBox.Items = arrayfun(@(x) {char(x)}, app.SelectedCountry.stateNames());
            app.StateorRegionListBox.Value = app.StateorRegionListBox.Items{1}; % default state
            app.StateorRegionListBoxValueChanged();
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            pos = strcmp(app.StateorRegionListBox.Items, app.StateorRegionListBox.Value);
            app.SelectedState = app.SelectedCountry.StateObjects(pos);
            if app.SelectedState.Name == "All" && app.SelectedCountry.Name == "Global"
                app.PlotTileName = "Globally";
            elseif app.SelectedState.Name == "All"
                app.PlotTileName = "in " + app.SelectedCountry.Name;
            else
                app.PlotTileName = "in " + app.SelectedState.Name;
            end
            
            % now we plot the data
            app.plotData();
        end

        % Selection changed function: DataToPlotButtonGroup
        function DataToPlotButtonGroupSelectionChanged(app, event)
            plotData(app);
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            plotData(app);
        end

        % Value changing function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanging(app, event)
            app.AveragingWindow = round(event.Value);  % ensuring whole numbers of slider value
            app.AveragedofdaysSlider.Value = round(event.Value);
            if app.AveragingWindow > 1
                app.Slider = " (" + string(app.AveragingWindow) + "-day mean)";
            else
                app.Slider = "";
            end
            plotData(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '                                     COVID-19 Data Visualization (Data source: Johns Hopkins University)';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Dates')
            ylabel(app.UIAxes, 'Counts')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionListBoxLabel = uilabel(app.UIFigure);
            app.StateorRegionListBoxLabel.Text = {'State or'; 'Region:'};
            app.StateorRegionListBox = uilistbox(app.UIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.DataToPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.DataToPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataToPlotButtonGroupSelectionChanged, true);
            app.DataToPlotButtonGroup.Title = 'Data To Plot';
            app.CasesButton = uiradiobutton(app.DataToPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathButton = uiradiobutton(app.DataToPlotButtonGroup);
            app.DeathButton.Text = 'Death';
            app.BothButton = uiradiobutton(app.DataToPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.AveragedofdaysSliderLabel = uilabel(app.UIFigure);
            app.AveragedofdaysSliderLabel.Text = {'Averaged'; '# of days'};
            app.AveragedofdaysSlider = uislider(app.UIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanging, true);
            app.AveragedofdaysSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.AveragedofdaysSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = COVID19APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end